classdef GaussianBeamMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        XaxislimitHigher            matlab.ui.control.NumericEditField
        YaxislimitLower             matlab.ui.control.NumericEditField
        YaxislimitLabel             matlab.ui.control.Label
        XaxislimitLower             matlab.ui.control.NumericEditField
        XaxislimitLabel             matlab.ui.control.Label
        AutosetXYaxisButton         matlab.ui.control.Button
        DeleteselectedrowsButton    matlab.ui.control.Button
        AddOpticsListBox            matlab.ui.control.ListBox
        AddOpticsListBoxLabel       matlab.ui.control.Label
        OuputText                   matlab.ui.control.TextArea
        SaveasButton                matlab.ui.control.Button
        OpenButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        WavelengthEditField         matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel  matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        GaussianBeamLabel           matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        LensTable                   matlab.ui.control.Table
        SetupDisplay                matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        LensTableContextMenus       matlab.ui.container.ContextMenu
        AddnewLine                  matlab.ui.container.Menu
        LensMenu                    matlab.ui.container.Menu
        CustomizedMenu              matlab.ui.container.Menu
        DeleteThisLine              matlab.ui.container.Menu
        ContextMenu                 matlab.ui.container.ContextMenu
        AutosetXaxisMenu            matlab.ui.container.Menu
        AutosetYaxisMenu            matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        PlotPointNum = 1000
        SelectedName (1,1)
        InitialBeam
        beams
        LensNameNum
        LensObject
        CurrentFile = "Temp.csv"
    end

    methods (Access = private)


        function LensObjectDownFcn(app, event)
            if (app.SelectedName) == ""
%                 [a,app.SelectedName] = min(...
%                     abs(mean(event.Source.XData)-app.LensTable.Data{2:end,2}));
%                 app.SelectedName = app.SelectedName+1;
                app.SelectedName =string( app.LensTable.Data{event.Source.UserData,9});
            end
        end

        function UpdateMain(app)
            app.Lamp.Color = 'r';

            app.LensTable.Data(2:end,:) = sortrows(app.LensTable.Data(2:end,:) ,2);
            if app.LensTable.Data{1,2} ~= app.LensTable.Data{1,6}
                app.LensTable.Data{1,6} = app.LensTable.Data{1,2};
            end

            LensTableData =table2cell( app.LensTable.Data);
            if strcmp(LensTableData{1,1},'Input Beam')
                app.InitialBeam = GaussianBeamInfo(LensTableData{1,1},LensTableData{1,2}...
                    ,LensTableData{1,3},LensTableData{1,5}*10^-6,LensTableData{1,6}*10^-3 ...
                    ,LensTableData{1,9},LensTableData{1,10},app.WavelengthEditField.Value*10^-9);
            else
                error('Input Beam is not detected')
            end
            app.beams = getBeams(app);
            UpdateTable(app)
            PlotSetup(app)


            app.Lamp.Color = 'g';
        end

        function PlotSetup(app)
            cla(app.SetupDisplay)
            %             if app.AutosetXYaxisButton.Value
            %                 Posmin = min(table2array(app.LensTable.Data(:,2)));
            %                 PosMax = max(table2array(app.LensTable.Data(:,2)));
            %                 Extentedpart = (PosMax - Posmin)*0.2;
            %                 app.SetupDisplay.XLim = [Posmin-Extentedpart , PosMax + Extentedpart];
            %             end
            app.GaussianBeamLabel.FontColor = app.beams{1}.Color;

            %             plot gaussian beams
            XlimitValue = app.SetupDisplay.XLim;
            PlotX =linspace( XlimitValue(1),XlimitValue(2),app.PlotPointNum);
            BeamWaist = getBeamRadius(app,PlotX);
            patch(app.SetupDisplay,[PlotX fliplr(PlotX)], [BeamWaist fliplr(-BeamWaist)], ...
                app.beams{1}.Color,'EdgeColor','none')
            %             if app.AutosetYaxisButton.Value
            %                 maxRadius = max(BeamWaist)*3;
            %                 app.SetupDisplay.YLim = [-maxRadius, maxRadius];
            %             end
            hold(app.SetupDisplay,'on')
            % Plot beam waist position
            for i = 1 : height(app.LensTable.Data)
                Beam = app.beams{i};
                plot(app.SetupDisplay,[Beam.WaistPosition *10^3, Beam.WaistPosition*10^3],...
                    [0, Beam.Waist*10^6],'k','LineWidth',3)
            end
            % Plot lens
            a= 0.025*(app.SetupDisplay.XLim(2) - app.SetupDisplay.XLim(1)); % horizontal radius
            b=0.4*(app.SetupDisplay.YLim(2) - app.SetupDisplay.YLim(1)); % vertical radius
            t1=0:0.01:pi;
            y0=0;

            LensTableData =table2cell( app.LensTable.Data);
            for i = 1 : height(app.LensTable.Data)
                if strcmp(LensTableData{i,1},'Lens')
                    x0=LensTableData{i,2}; % x0,y0 ellipse centre coordinates
                    x1=x0+a*cos(t1);
                    y1=y0+b*sin(t1);
                    app.LensObject{i} = patch(app.SetupDisplay,[x1 fliplr(x1)], [y1 fliplr(-y1)], ...
                        [0.74 0.85 0.91],'FaceAlpha',0.7,'EdgeColor','none');
                    app.LensObject{i}.ButtonDownFcn =createCallbackFcn(app, @LensObjectDownFcn, true);
                    app.LensObject{i}.UserData = i;
                end
            end

        end

        function BeamRadius = getBeamRadius(app,xVec)
            BeamRadius = zeros(1,length(xVec));
            for i = 1:length(xVec)
                x = xVec(i);
                Pos = sum(x>table2array( app.LensTable.Data(2:end,2)));
                if Pos == 0
                    BeamRadius(i) = beamWidth(app.InitialBeam,x*10^-3-app.InitialBeam.WaistPosition)*10^6;
                else
                    BeamRadius(i)  = beamWidth(app.beams{Pos+1},x*10^-3-app.beams{Pos+1}.WaistPosition)*10^6;
                end
            end
        end

        function beams = getBeams(app)


            LensTableData =table2cell( app.LensTable.Data);
            [rows,~] = size(LensTableData);
            beams = {app.InitialBeam};
            if rows>1
                for i = 2:rows
                    if strcmp(LensTableData{i,1},'Lens')
                        if strcmp(LensTableData{i,9},'None')
                            app.LensNameNum = app.LensNameNum+1;
                            app.LensTable.Data{i,9} = {sprintf('L%i',app.LensNameNum)};
                        end
                        Previousbeam = beams{i-1};
                        Focallength = LensTableData{i,4}/1000;
                        a = TransimitionABCD(Previousbeam,[1,0;Previousbeam.n/Focallength,1]...
                            ,LensTableData{i,2}/1000);
                        beams{i} = GaussianBeamInfo('Lens',LensTableData{i,2},Focallength...
                            ,a.Waist,a.WaistPosition,'None',LensTableData{i,10},a.Wavelength);
                    elseif strcmp(LensTableData{i,1},'ABCD')
                        error('TO BE DONE')
                    else
                        error(['Unknown type of optical element: ',LensTableData{i,1}])
                    end
                end
            end
        end

        function UpdateTable(app)
            LensTableData =table2cell( app.LensTable.Data);
            [rows,~] = size(LensTableData);
            for i = 1:rows
                if(i>=2)
                    app.LensTable.Data{i,3} = app.LensTable.Data{i,2} - app.LensTable.Data{i-1,2};
                end
                if strcmp(LensTableData{i,9},'None')
                    if strcmp(LensTableData{i,1},'Lens')
                        app.LensNameNum = app.LensNameNum+1;
                        app.LensTable.Data{i,9} = sprintf('L%i',app.LensNameNum);
                    end
                end
                app.LensTable.Data{i,5} = app.beams{i}.Waist*10^6;
                app.LensTable.Data{i,6} = app.beams{i}.WaistPosition*10^3;
                app.LensTable.Data{i,7} =rayleighLength(app.beams{i})*10^3;
                app.LensTable.Data{i,8} = DivAngle(app.beams{i})*10^3;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.SelectedName = "";
            app.LensTable.Data = readtable("Default.csv");
            app.LensNameNum = 0;
            UpdateMain(app);
            AutosetXYaxisButtonPushed(app);
        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)
            value = app.WavelengthEditField.Value;
            app.InitialBeam.Wavelength = value*10^-9;
            UpdateMain(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            writetable(app.LensTable.Data,app.CurrentFile);
            app.OuputText.Value  = "file has been saved as "+app.CurrentFile;
        end

        % Menu selected function: LensMenu
        function LensMenuSelected(app, event)
            app.LensTable.Data =  [app.LensTable.Data;
                cell2table({'Lens',60,50,100,180,0,1.49,0,'None','None'})];
            UpdateMain(app)
        end

        % Cell edit callback: LensTable
        function LensTableCellEdit(app, event)
            switch event.Indices(2)
                case 3
                if event.Indices(1) == 1
                    app.LensTable.Data{1,3} = app.LensTable.Data{1,2} ;
                else
                    app.LensTable.Data{event.Indices(1),2} ...
                        = app.LensTable.Data{event.Indices(1),3} + ...
                        app.LensTable.Data{event.Indices(1)-1,2} ;
                end
            end

            UpdateMain(app)
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            [file,path] = uigetfile('*.csv',...
                'Select an csv file to save',app.CurrentFile);
            if file == 0
                app.OuputText.Value  = 'No file was selected!';
            else
                app.CurrentFile = [path,file];
                writetable(app.LensTable.Data,app.CurrentFile);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file,path] = uigetfile('*.csv',...
                'Select an csv file to open',app.CurrentFile);
            if file == 0
                app.OuputText.Value  = 'No file was selected!';
            else
                app.CurrentFile = [path,file];
                app.LensTable.Data = readtable(app.CurrentFile);
            end
        end

        % Callback function: DeleteThisLine, DeleteselectedrowsButton
        function DeleteThisLineMenuSelected(app, event)
            if length(app.LensTable.Selection) < 1
                app.OuputText.FontColor = 'r';
                app.OuputText.Value  = 'Please select rows to delete!';
            else
                SelectedRows = app.LensTable.Selection(:,1);
                SelectedRows = unique(SelectedRows);
                if  min(SelectedRows) <=1
                    app.OuputText.FontColor = 'r';
                    app.OuputText.Value  = 'The first row is unable to delete! Because it is the input beam.';
                else
                    app.LensTable.Data(SelectedRows,:) = [];
                    UpdateMain(app);
                end
            end
        end

        % Double-clicked callback: AddOpticsListBox
        function AddOpticsDropDownClicked(app, event)
            if(strcmp(app.AddOpticsDropDown.Value ,'Lens')  )
                LensMenuSelected(app, event)
            end
        end

        % Menu selected function: AutosetXaxisMenu
        function AutosetXaxisMenuSelected(app, event)

            Posmin = min(table2array(app.LensTable.Data(:,2)));
            PosMax = max(table2array(app.LensTable.Data(:,2)));
            Extentedpart = (PosMax - Posmin)*0.2;
            app.SetupDisplay.XLim = [Posmin-Extentedpart , PosMax + Extentedpart];
            PlotSetup(app)
        end

        % Menu selected function: AutosetYaxisMenu
        function AutosetYaxisMenuSelected(app, event)
            XlimitValue = app.SetupDisplay.XLim;
            PlotX =linspace( XlimitValue(1),XlimitValue(2),app.PlotPointNum);
            BeamWaist = getBeamRadius(app,PlotX);
            maxRadius = max(BeamWaist)*3;
            app.SetupDisplay.YLim = [-maxRadius, maxRadius];
            PlotSetup(app)
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if (app.SelectedName) ~= ""
                app.SelectedName = "";
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)


            if (app.SelectedName) ~= ""
                p = event.Source.CurrentPoint;
                x = p(1);
                y = p(2);
                x1 = app.SetupDisplay.InnerPosition(1)+ app.CenterPanel.Position(1);

                x2 = sum(app.SetupDisplay.InnerPosition([1 3]))+ app.CenterPanel.Position(1) ;
                %                 y1 = app.SetupDisplay.InnerPosition(2) + app.CenterPanel.Position(2);
                %                 y2 = sum(app.SetupDisplay.InnerPosition([2 4]))+ app.CenterPanel.Position(2);
                xl = app.SetupDisplay.XLim;
                xax = xl(1)+(xl(2)-xl(1))*(x-x1)/(x2-x1);
                if x>x1 && x<x2

                    app.LensTable.Data{...
                        strcmp(app.LensTable.Data{:,9},app.SelectedName),2} = xax;
                    UpdateMain(app)

                end
            end
        end

        % Button pushed function: AutosetXYaxisButton
        function AutosetXYaxisButtonPushed(app, event)
            app.AutosetXaxisMenuSelected(app);
            app.AutosetYaxisMenuSelected(app)
        end

        % Value changed function: XaxislimitLower
        function XaxislimitLowerValueChanged(app, event)
            value = app.XaxislimitLower.Value;
            if app.XaxislimitLower.Value < app.XaxislimitHigher.Value
                app.SetupDisplay.XLim(1) = value;
            else
                app.XaxislimitLower.Value = app.XaxislimitHigher.Value;
                app.XaxislimitHigher.Value = value;
                app.SetupDisplay.XLim(1) = app.XaxislimitLower.Value;
                app.SetupDisplay.XLim(2) = app.XaxislimitHigher.Value;
            end
            PlotSetup(app)
        end

        % Value changed function: XaxislimitHigher
        function XaxislimitHigherValueChanged(app, event)
            value = app.XaxislimitHigher.Value;
            if app.XaxislimitLower.Value < app.XaxislimitHigher.Value
                app.SetupDisplay.XLim(2) = value;
            else
                XaxislimitLowerValueChanged(app, event)
            end
            PlotSetup(app)
        end

        % Value changed function: YaxislimitLower
        function YaxislimitLowerValueChanged(app, event)
            value = app.YaxislimitLower.Value;
            app.SetupDisplay.YLim = [- value, value];
            PlotSetup(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GaussianBeamLabel = uilabel(app.LeftPanel);
            app.GaussianBeamLabel.Text = 'Gaussian Beam';
            app.Lamp = uilamp(app.LeftPanel);
            app.WavelengthnmEditFieldLabel = uilabel(app.LeftPanel);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WavelengthEditField.Limits = [0 Inf];
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            app.WavelengthEditField.Value = 461;
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveasButton = uibutton(app.LeftPanel, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as';
            app.OuputText = uitextarea(app.LeftPanel);
            app.OuputText.Editable = 'off';
            app.OuputText.Value = {'Welcome!'};
            app.AddOpticsListBoxLabel = uilabel(app.LeftPanel);
            app.AddOpticsListBoxLabel.Text = 'Add Optics';
            app.AddOpticsListBox = uilistbox(app.LeftPanel);
            app.AddOpticsListBox.Items = {'Lens', 'Customized'};
            app.AddOpticsListBox.DoubleClickedFcn = createCallbackFcn(app, @AddOpticsDropDownClicked, true);
            app.AddOpticsListBox.Value = 'Lens';
            app.DeleteselectedrowsButton = uibutton(app.LeftPanel, 'push');
            app.DeleteselectedrowsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteThisLineMenuSelected, true);
            app.DeleteselectedrowsButton.Text = 'Delete selected rows';
            app.AutosetXYaxisButton = uibutton(app.LeftPanel, 'push');
            app.AutosetXYaxisButton.ButtonPushedFcn = createCallbackFcn(app, @AutosetXYaxisButtonPushed, true);
            app.AutosetXYaxisButton.Text = 'Autoset X&Y axis';
            app.XaxislimitLabel = uilabel(app.LeftPanel);
            app.XaxislimitLabel.Text = 'X axis limit';
            app.XaxislimitLower = uieditfield(app.LeftPanel, 'numeric');
            app.XaxislimitLower.ValueChangedFcn = createCallbackFcn(app, @XaxislimitLowerValueChanged, true);
            app.YaxislimitLabel = uilabel(app.LeftPanel);
            app.YaxislimitLabel.Text = 'Y axis limit';
            app.YaxislimitLower = uieditfield(app.LeftPanel, 'numeric');
            app.YaxislimitLower.Limits = [0 Inf];
            app.YaxislimitLower.ValueChangedFcn = createCallbackFcn(app, @YaxislimitLowerValueChanged, true);
            app.YaxislimitLower.Value = 500;
            app.XaxislimitHigher = uieditfield(app.LeftPanel, 'numeric');
            app.XaxislimitHigher.ValueChangedFcn = createCallbackFcn(app, @XaxislimitHigherValueChanged, true);
            app.XaxislimitHigher.Value = 130;
            app.CenterPanel = uipanel(app.GridLayout);
            app.SetupDisplay = uiaxes(app.CenterPanel);
            xlabel(app.SetupDisplay, 'position (mm)')
            ylabel(app.SetupDisplay, 'Beam width(μm)')
            app.LensTable = uitable(app.CenterPanel);
            app.LensTable.ColumnName = {'Optics'; 'Position/n (mm)'; 'Relative position (mm)'; 'Properties'; 'Waist(μm)'; 'Waist Position(mm)'; 'Rayleigh range(mm)'; 'Divergence(mrad)'; 'Name'; 'Lock'};
            app.LensTable.ColumnSortable = [true true false false false false false false false false];
            app.LensTable.ColumnEditable = [false true true true true false false false false false];
            app.LensTable.CellEditCallback = createCallbackFcn(app, @LensTableCellEdit, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.LensTableContextMenus = uicontextmenu(app.UIFigure);
            app.AddnewLine = uimenu(app.LensTableContextMenus);
            app.AddnewLine.Text = 'Add a new optics';
            app.LensMenu = uimenu(app.AddnewLine);
            app.LensMenu.MenuSelectedFcn = createCallbackFcn(app, @LensMenuSelected, true);
            app.LensMenu.Text = 'Lens';
            app.CustomizedMenu = uimenu(app.AddnewLine);
            app.CustomizedMenu.Text = 'Customized';
            app.DeleteThisLine = uimenu(app.LensTableContextMenus);
            app.DeleteThisLine.MenuSelectedFcn = createCallbackFcn(app, @DeleteThisLineMenuSelected, true);
            app.DeleteThisLine.Text = 'Delete seleted row';
            % Assign app.LensTableContextMenus
            app.LensTable.ContextMenu = app.LensTableContextMenus;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.AutosetXaxisMenu = uimenu(app.ContextMenu);
            app.AutosetXaxisMenu.MenuSelectedFcn = createCallbackFcn(app, @AutosetXaxisMenuSelected, true);
            app.AutosetXaxisMenu.Text = 'Autoset X axis';
            app.AutosetYaxisMenu = uimenu(app.ContextMenu);
            app.AutosetYaxisMenu.MenuSelectedFcn = createCallbackFcn(app, @AutosetYaxisMenuSelected, true);
            app.AutosetYaxisMenu.Text = 'Autoset Y axis';
            % Assign app.ContextMenu
            app.LeftPanel.ContextMenu = app.ContextMenu;
            app.SetupDisplay.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaussianBeamMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end